classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IsdeployedLamp                matlab.ui.control.Lamp
        IsdeployedLampLabel           matlab.ui.control.Label
        AppbuilddateTextArea          matlab.ui.control.TextArea
        AppbuilddateTextAreaLabel     matlab.ui.control.Label
        AppversionTextArea            matlab.ui.control.TextArea
        AppversionTextAreaLabel       matlab.ui.control.Label
        RuntimeversionTextArea        matlab.ui.control.TextArea
        RuntimeversionTextAreaLabel   matlab.ui.control.Label
        CompilerversionTextArea       matlab.ui.control.TextArea
        CompilerversionTextAreaLabel  matlab.ui.control.Label
        QuitButton                    matlab.ui.control.Button
        TestapplicationforMatlabCompilerRuntimeLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        compilerVersion = '23.2.0.2515942 (R2023b) Update 7'; % Matlab version application was compiled from
        builddate = datetime(2024,4,4); % Date that application was made.
        version = 1.0;
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isdeployed()
                app.IsdeployedLamp.Color = [0 1 0];
            end
            [major, minor, update] = mcrversion();
            app.RuntimeversionTextArea.Value = string(major) + "." + string(minor) + "." + string(update);
            app.RuntimeversionTextArea.Editable = false;
            app.CompilerversionTextArea.Value = app.compilerVersion;   
            app.CompilerversionTextArea.Editable = false;
            app.AppbuilddateTextArea.Value = string(app.builddate);
            app.AppbuilddateTextArea.Editable = false;
            app.AppversionTextArea.Value = string(app.version);
            app.AppversionTextArea.Editable = false;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestapplicationforMatlabCompilerRuntimeLabel = uilabel(app.UIFigure);
            app.TestapplicationforMatlabCompilerRuntimeLabel.Text = 'Test application for Matlab Compiler Runtime';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.CompilerversionTextAreaLabel = uilabel(app.UIFigure);
            app.CompilerversionTextAreaLabel.Text = 'Compiler version';
            app.CompilerversionTextArea = uitextarea(app.UIFigure);
            app.RuntimeversionTextAreaLabel = uilabel(app.UIFigure);
            app.RuntimeversionTextAreaLabel.Text = 'Runtime version';
            app.RuntimeversionTextArea = uitextarea(app.UIFigure);
            app.AppversionTextAreaLabel = uilabel(app.UIFigure);
            app.AppversionTextAreaLabel.Text = 'App version';
            app.AppversionTextArea = uitextarea(app.UIFigure);
            app.AppbuilddateTextAreaLabel = uilabel(app.UIFigure);
            app.AppbuilddateTextAreaLabel.Text = 'App build date';
            app.AppbuilddateTextArea = uitextarea(app.UIFigure);
            app.IsdeployedLampLabel = uilabel(app.UIFigure);
            app.IsdeployedLampLabel.Text = 'Is deployed';
            app.IsdeployedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end